classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PLOTButton                   matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        AmplitudeKnobLabel           matlab.ui.control.Label
        AmplitudeKnob                matlab.ui.control.Knob
        FrequencyKnobLabel           matlab.ui.control.Label
        FrequencyKnob                matlab.ui.control.Knob
        CRODESIGNLabel               matlab.ui.control.Label
        TypeofWaveformDropDownLabel  matlab.ui.control.Label
        TypeofWaveformDropDown       matlab.ui.control.DropDown
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Value changed function: AmplitudeKnob
        function AmplitudeKnobValueChanged(app, event)
            value = app.AmplitudeKnob.Value;         
        end

        % Value changed function: FrequencyKnob
        function FrequencyKnobValueChanged(app, event)
            value = app.FrequencyKnob.Value;        
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
              t= 0 : 0.01: 0.5*pi;
              ax= app.UIAxes;
              if isequal(app.TypeofWaveformDropDown.Value,'Sine')
                    y= app.AmplitudeKnob.Value*sin(2*pi*app.FrequencyKnob.Value*t);
              else 
                  y= app.AmplitudeKnob.Value*square(2*pi*app.FrequencyKnob.Value*t)
              end  
              plot(ax,t,y);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plot')
            xlabel(app.UIAxes, 'Frequency')
            ylabel(app.UIAxes, 'Amplitude')
            app.AmplitudeKnobLabel = uilabel(app.UIFigure);
            app.AmplitudeKnobLabel.Text = 'Amplitude';
            app.AmplitudeKnob = uiknob(app.UIFigure, 'continuous');
            app.AmplitudeKnob.Limits = [0 10];
            app.AmplitudeKnob.ValueChangedFcn = createCallbackFcn(app, @AmplitudeKnobValueChanged, true);
            app.FrequencyKnobLabel = uilabel(app.UIFigure);
            app.FrequencyKnobLabel.Text = 'Frequency';
            app.FrequencyKnob = uiknob(app.UIFigure, 'continuous');
            app.FrequencyKnob.ValueChangedFcn = createCallbackFcn(app, @FrequencyKnobValueChanged, true);
            app.CRODESIGNLabel = uilabel(app.UIFigure);
            app.CRODESIGNLabel.Text = 'CRO DESIGN';
            app.TypeofWaveformDropDownLabel = uilabel(app.UIFigure);
            app.TypeofWaveformDropDownLabel.Text = 'TypeofWaveform';
            app.TypeofWaveformDropDown = uidropdown(app.UIFigure);
            app.TypeofWaveformDropDown.Items = {'Sine', 'Square'};
            app.TypeofWaveformDropDown.Value = 'Sine';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end